classdef NeuroPatchbay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FileMenu               matlab.ui.container.Menu
        SavePatchbayMenu       matlab.ui.container.Menu
        LoadPatchbayMenu       matlab.ui.container.Menu
        ObjectPropertiesPanel  matlab.ui.container.Panel
        UITable                matlab.ui.control.Table
        TabGroup               matlab.ui.container.TabGroup
        BlackrockNeurotechTab  matlab.ui.container.Tab
        AmpButton              matlab.ui.control.Button
        NSPButton              matlab.ui.control.Button
        RippleTab              matlab.ui.container.Tab
        NihonKohdenTab         matlab.ui.container.Tab
        TBarButton             matlab.ui.control.Button
        PalettePanel           matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
        ObjectContextMenu      matlab.ui.container.ContextMenu
        ConnectMenu            matlab.ui.container.Menu
        DeleteMenu             matlab.ui.container.Menu
    end

    
    properties (Access = private)
        DynamicComponents      matlab.ui.container.Menu
        ObjectList = struct
        ObjectGraph
        ObjectImage
        ObjectGraphPlot
        SelectedObject
        LastObject
    end
    
    methods (Access = private)
        function selectObject(app,source,event)
            switch event.Button
                case 1
                    getObjectIndex(app);
                    switch app.UIFigure.SelectionType
                        case 'normal'
                            if ~isempty(app.LastObject) && isvalid(app.LastObject)
                                app.LastObject.Selected = 'off';
                            end
                    end
                    source.Selected  = 'on';
                    objectName = getObjectName(app);
                    showObjectProperties(app,objectName)
                    cursorCorrection = event.IntersectionPoint(1:2) - [source.XData(1) source.YData(1)];
                    objectImageSize = [diff(source.XData) diff(source.YData)];
                    app.UIFigure.Pointer = 'fleur';
                    app.UIFigure.WindowButtonMotionFcn = {@app.dragObject,source,objectImageSize,cursorCorrection};
                    app.UIFigure.WindowButtonUpFcn = {@app.dragObjectDone,source};
                case 2
                case 3
                    getObjectIndex(app);
                otherwise
            end
        end

        function selectObjectEdge(app,source,event)
            test = 1;
        end

        function deselectObject(app)
            h = findobj(app.UIAxes.Children,'Selected','on');
            for i = 1:numel(h)
                h(i).Selected = 'off';
            end
            app.SelectedObject = [];
            app.ConnectMenu.Enable = 0;
        end

        function dragObject(app,~,~,callingSource,objectSize,cursorCorrection)
            moveDifference = callingSource.Parent.CurrentPoint(1,1:2) - ([callingSource.XData(1) callingSource.YData(1)]+cursorCorrection);
            callingSource.XData = [callingSource.XData(1) + moveDifference(1) callingSource.XData(1) + moveDifference(1) + objectSize(1)];
            callingSource.YData = [callingSource.YData(1) + moveDifference(2) callingSource.YData(1) + moveDifference(2) + objectSize(2)];
            app.ObjectGraphPlot.XData(app.SelectedObject) = sum(callingSource.XData)/2;
            app.ObjectGraphPlot.YData(app.SelectedObject) = sum(callingSource.YData)/2;
        end

        function dragObjectDone(app,~,~,callingSource)
            app.UIFigure.Pointer = 'arrow';
            app.LastObject = callingSource;
            % callingSource.Selected = 'off';
            app.UIFigure.WindowButtonMotionFcn = [];
            app.UIFigure.WindowButtonUpFcn = [];
        end

        function showObjectProperties(app,objectName)
            % if numel(objectName)>1
            %     objectName = objectName(1);
            % end
            % app.UITable.Data = struct2tree(app.ObjectList.(objectName{1}),app.Tree);
        end

        function objectName = getObjectName(app)
            objectName = cellstr(app.ObjectGraphPlot.NodeLabel(app.SelectedObject));
        end

        function addObjectToPalette(app,source)
            % Add Object to ObjectList
            newObjectName = addObjectToList(app,source);

            % Get ImageAsset and Plot
            icnMat = imread(source.Icon);
            icnMat = flip(icnMat,1);
            xyCenter = size(icnMat,1,2)./2;
            app.ObjectImage = image(app.UIAxes,icnMat,'XData',[-xyCenter(1) xyCenter(1)],'YData',[-xyCenter(2) xyCenter(2)],'ButtonDownFcn',@app.selectObject,'Tag',newObjectName);
            app.ObjectImage.ContextMenu = app.ObjectContextMenu;
            
            % Add associated node to ObjectGraph and replot
            addObjectNode(app,newObjectName)
        end

        function newObjectName = addObjectToList(app,source)
            jsonText = fileread(source.UserData);
            tmpStruct = jsondecode(jsonText);
            objectName = tmpStruct.Name;
            count = 1;
            tmpName = [objectName,int2str(count)];
            while ismember(tmpName,fieldnames(app.ObjectList))
                count = count + 1;
                tmpName = regexprep(tmpName,'\d+$',int2str(count));
            end
            newObjectName = tmpName;            
            app.ObjectList.(newObjectName) = tmpStruct;
        end

        function removeObjectFromList(app,objectName)
            [~,idx] = intersect(app.ObjectList,objectName,'stable');
            app.ObjectList(idx) = [];
        end

        function renameObjectInList(app,oldName,newName)
            % idx = strcmp(oldName,app.ObjectList);
            % app.ObjectList(idx) = cellstr(newName);
            app.ObjectList.(newName) = app.ObjectList.(oldName);
            app.ObjectList = rmfield(app.ObjectList,oldName);
        end

        function getObjectIndex(app)
            idx = find(strcmp(app.UIFigure.CurrentObject.Tag,app.ObjectGraphPlot.NodeLabel));
            switch app.UIFigure.SelectionType
                case 'normal'
                    app.SelectedObject = idx;
                case 'alt'
                    app.SelectedObject = unique([app.SelectedObject,idx]);
            end
            if numel(app.SelectedObject)==2
                app.ConnectMenu.Enable = 1;
            else
                app.ConnectMenu.Enable = 0;
            end
        end
        
        function removeObjectImage(app,objectName)
            for i = 1:numel(objectName)
                imageHandle = findobj(app.UIAxes.Children,'Tag',objectName{i});
                delete(imageHandle)
            end
        end

        function addObjectNode(app,objectName)
            app.ObjectGraph = addnode(app.ObjectGraph,objectName);
            xdata = [app.ObjectGraphPlot.XData sum(app.ObjectImage.XData)/2];
            ydata = [app.ObjectGraphPlot.YData sum(app.ObjectImage.YData)/2];
            overwriteGraphPlot(app,xdata,ydata)
        end

        function removeObjectNode(app)
            app.ObjectGraph = rmnode(app.ObjectGraph,app.SelectedObject);
            xdata = app.ObjectGraphPlot.XData;
            xdata(app.SelectedObject) = [];
            ydata = app.ObjectGraphPlot.YData;
            ydata(app.SelectedObject) = [];
            overwriteGraphPlot(app,xdata,ydata)
        end

        function addEdgeBetweenObjects(app)
            objectName = getObjectName(app);
            indEdge = findedge(app.ObjectGraph,objectName{:});
            if indEdge ~= 0
                return
            end
            app.ObjectGraph = addedge(app.ObjectGraph,objectName{:});
            xdata = app.ObjectGraphPlot.XData;
            ydata = app.ObjectGraphPlot.YData;
            overwriteGraphPlot(app,xdata,ydata)
        end

        function removeEdgeBetweenObjects(app)

        end

        function editEdgePropertyBetweenObjects(app)
        
        end

        function overwriteGraphPlot(app,xdata,ydata)
            delete(app.ObjectGraphPlot)
            app.ObjectGraphPlot = plot(app.UIAxes,app.ObjectGraph,'XData',xdata,'YData',ydata);
            app.ObjectGraphPlot.ButtonDownFcn = @app.selectObjectEdge;
            uistack(app.ObjectGraphPlot,'bottom')
        end

        function deleteSelectedObject(app)
            if isempty(app.SelectedObject)
                return
            end
            objectName = getObjectName(app);
            removeObjectImage(app,objectName)
            removeObjectNode(app)
            removeObjectFromList(app,objectName);
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.XColor = [1 1 1];
            app.UIAxes.YColor = [1 1 1];

            app.ObjectGraph = digraph;
            app.ObjectGraphPlot = plot(app.UIAxes,app.ObjectGraph);
            
            pathToMLAPP = fileparts(mfilename('fullpath'));
            userDataFromComponents(app,pathToMLAPP)
        end

        % Button pushed function: AmpButton, NSPButton, TBarButton
        function objectSelected(app, event)
            source = event.Source;
            addObjectToPalette(app,source)
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'f12'
                    if any(contains(event.Modifier,'control'))
                        keyboard
                    end
                case 'delete'
                    deleteSelectedObject(app)
            end
        end

        % Menu selected function: DeleteMenu
        function deleteSelectedObjectCallback(app, event)
            deleteSelectedObject(app)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            deselectObject(app)
        end

        % Menu selected function: ConnectMenu
        function ConnectMenuSelected(app, event)
            addEdgeBetweenObjects(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SavePatchbayMenu = uimenu(app.FileMenu);
            app.SavePatchbayMenu.Text = 'Save Patchbay';
            app.LoadPatchbayMenu = uimenu(app.FileMenu);
            app.LoadPatchbayMenu.Text = 'Load Patchbay';
            app.PalettePanel = uipanel(app.UIFigure);
            app.PalettePanel.Title = 'Palette';
            app.UIAxes = uiaxes(app.PalettePanel);
            app.UIAxes.XLim = [-500 500];
            app.UIAxes.YLim = [-500 500];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BlackrockNeurotechTab = uitab(app.TabGroup);
            app.BlackrockNeurotechTab.Title = 'Blackrock Neurotech';
            app.NSPButton = uibutton(app.BlackrockNeurotechTab, 'push');
            app.NSPButton.ButtonPushedFcn = createCallbackFcn(app, @objectSelected, true);
            app.NSPButton.Icon = fullfile(pathToMLAPP, 'assets', 'AppIcons', 'electricity-256.png');
            app.NSPButton.Text = 'NSP';
            app.AmpButton = uibutton(app.BlackrockNeurotechTab, 'push');
            app.AmpButton.ButtonPushedFcn = createCallbackFcn(app, @objectSelected, true);
            app.AmpButton.Icon = fullfile(pathToMLAPP, 'assets', 'AppIcons', 'electricity-256.png');
            app.AmpButton.Text = 'Amp';
            app.RippleTab = uitab(app.TabGroup);
            app.RippleTab.Title = 'Ripple';
            app.NihonKohdenTab = uitab(app.TabGroup);
            app.NihonKohdenTab.Title = 'NihonKohden';
            app.TBarButton = uibutton(app.NihonKohdenTab, 'push');
            app.TBarButton.ButtonPushedFcn = createCallbackFcn(app, @objectSelected, true);
            app.TBarButton.Icon = fullfile(pathToMLAPP, 'assets', 'AppIcons', 'square-24.png');
            app.TBarButton.Text = 'TBar';
            app.ObjectPropertiesPanel = uipanel(app.UIFigure);
            app.ObjectPropertiesPanel.Title = 'Object Properties';
            app.UITable = uitable(app.ObjectPropertiesPanel);
            app.UITable.ColumnName = {'Property'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.ObjectContextMenu = uicontextmenu(app.UIFigure);
            app.ConnectMenu = uimenu(app.ObjectContextMenu);
            app.ConnectMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnectMenuSelected, true);
            app.ConnectMenu.Enable = 'off';
            app.ConnectMenu.Text = 'Connect';
            app.DeleteMenu = uimenu(app.ObjectContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @deleteSelectedObjectCallback, true);
            app.DeleteMenu.Text = 'Delete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NeuroPatchbay

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end